classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PlotButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function loadb(app)
            global data
            prefs = setdbprefs('DataReturnFormat');
            setdbprefs('DataReturnFormat','table')
            conn = database('MySQL','vrk','');
            curs = exec(conn,['SELECT DISTINCT ' ...
                'snr_h03.id, ' ...
                'snr_h03.rec_date, ' ...
                '`snr_h03.baa10.ce311.xq01`.`value` AS "MW",' ... #MW
                '`snr_h03.baa10.ce312.xq01`.`value` AS "MVar",' ... #MVar
                '`snr_h03.mey40.cs001.xq01`.`value` AS "Speed",' ... #Speed
                '`snr_h03.pcb80.cf001.xq01`.`value` AS "FlowGenCwInlet2StatorAirCooler",' ... #FlowGenCwInlet2StatorAirCooler
                '`snr_h03.pcb80.ct005.xq01`.`value` AS "TempGenCwAirCoolerOut",' ... #TempGenCwAirCoolerOut
                '`snr_h03.pcb80.ct002.xq01`.`value` AS "TempGenCwAirCoolerIn",' ... #TempGenCwAirCoolerIn
                '`snr_h03.mka81.ct001.xq01`.`value` AS "StatorHotAirTemp1",' ... #StatorHotAirTemp1
                '`snr_h03.mka82.ct001.xq01`.`value` AS "StatorColdAirTemp1",' ... #StatorColdAirTemp1
                '`snr_h03.mka81.ct002.xq01`.`value` AS "StatorHotAirTemp2",' ... #StatorHotAirTemp2
                '`snr_h03.mka82.ct002.xq01`.`value` AS "StatorColdAirTemp2",' ... #StatorColdAirTemp2
                '`snr_h03.mka81.ct003.xq01`.`value` AS "StatorHotAirTemp3",' ... #StatorHotAirTemp3
                '`snr_h03.mka82.ct003.xq01`.`value` AS "StatorColdAirTemp3",' ... #StatorColdAirTemp3
                '`snr_h03.mka81.ct004.xq01`.`value` AS "StatorHotAirTemp4",' ... #StatorHotAirTemp4
                '`snr_h03.mka82.ct004.xq01`.`value` AS "StatorColdAirTemp4",' ... #StatorColdAirTemp4
                '`snr_h03.mka81.ct005.xq01`.`value` AS "StatorHotAirTemp5",' ... #StatorHotAirTemp5
                '`snr_h03.mka82.ct005.xq01`.`value` AS "StatorColdAirTemp5",' ... #StatorColdAirTemp5
                '`snr_h03.mka81.ct006.xq01`.`value` AS "StatorHotAirTemp6",' ... #StatorHotAirTemp6
                '`snr_h03.mka82.ct006.xq01`.`value` AS "StatorColdAirTemp6",' ... #StatorColdAirTemp6
                '`snr_h03.mka81.ct007.xq01`.`value` AS "StatorHotAirTemp7",' ... #StatorHotAirTemp7
                '`snr_h03.mka82.ct007.xq01`.`value` AS "StatorColdAirTemp7",' ... #StatorColdAirTemp7
                '`snr_h03.mka81.ct008.xq01`.`value` AS "StatorHotAirTemp8",' ... #StatorHotAirTemp8
                '`snr_h03.mka82.ct008.xq01`.`value` AS "StatorColdAirTemp8",' ... #StatorColdAirTemp8
                '`snr_h03.mka81.ct009.xq01`.`value` AS "StatorHotAirTemp9",' ... #StatorHotAirTemp9
                '`snr_h03.mka82.ct009.xq01`.`value` AS "StatorColdAirTemp9",' ... #StatorColdAirTemp9
                '`snr_h03.mka81.ct010.xq01`.`value` AS "StatorHotAirTemp10",' ... #StatorHotAirTemp10
                '`snr_h03.mka82.ct010.xq01`.`value` AS "StatorColdAirTemp10",' ... #StatorColdAirTemp10
                '`snr_h03.mka81.ct011.xq01`.`value` AS "StatorHotAirTemp11",' ... #StatorHotAirTemp11
                '`snr_h03.mka82.ct011.xq01`.`value` AS "StatorColdAirTemp11",' ... #StatorColdAirTemp11
                '`snr_h03.mka81.ct012.xq01`.`value` AS "StatorHotAirTemp12",' ... #StatorHotAirTemp12
                '`snr_h03.mka82.ct012.xq01`.`value` AS "StatorColdAirTemp12"' ... #StatorColdAirTemp12
                'FROM ' ...
                'snr_h03 ' ...
                'INNER JOIN `snr_h03.baa10.ce311.xq01` ON snr_h03.id = `snr_h03.baa10.ce311.xq01`.id ' ... #MW
                'INNER JOIN `snr_h03.baa10.ce312.xq01` ON snr_h03.id = `snr_h03.baa10.ce312.xq01`.id ' ... #MVar
                'INNER JOIN `snr_h03.mey40.cs001.xq01` ON snr_h03.id = `snr_h03.mey40.cs001.xq01`.id ' ... #Speed
                'INNER JOIN `snr_h03.pcb80.cf001.xq01` ON snr_h03.id = `snr_h03.pcb80.cf001.xq01`.id ' ... #FlowGenCwInlet2StatorAirCooler
                'INNER JOIN `snr_h03.pcb80.ct005.xq01` ON snr_h03.id = `snr_h03.pcb80.ct005.xq01`.id ' ... #TempGenCwAirCoolerOut
                'INNER JOIN `snr_h03.pcb80.ct002.xq01` ON snr_h03.id = `snr_h03.pcb80.ct002.xq01`.id ' ... #TempGenCwAirCoolerIn
                'INNER JOIN `snr_h03.mka81.ct001.xq01` ON snr_h03.id = `snr_h03.mka81.ct001.xq01`.id ' ... #StatorHotAirTemp1
                'INNER JOIN `snr_h03.mka82.ct001.xq01` ON snr_h03.id = `snr_h03.mka82.ct001.xq01`.id ' ... #StatorColdAirTemp1
                'INNER JOIN `snr_h03.mka81.ct002.xq01` ON snr_h03.id = `snr_h03.mka81.ct002.xq01`.id ' ... #StatorHotAirTemp2
                'INNER JOIN `snr_h03.mka82.ct002.xq01` ON snr_h03.id = `snr_h03.mka82.ct002.xq01`.id ' ... #StatorColdAirTemp2
                'INNER JOIN `snr_h03.mka81.ct003.xq01` ON snr_h03.id = `snr_h03.mka81.ct003.xq01`.id ' ... #StatorHotAirTemp3
                'INNER JOIN `snr_h03.mka82.ct003.xq01` ON snr_h03.id = `snr_h03.mka82.ct003.xq01`.id ' ... #StatorColdAirTemp3
                'INNER JOIN `snr_h03.mka81.ct004.xq01` ON snr_h03.id = `snr_h03.mka81.ct004.xq01`.id ' ... #StatorHotAirTemp4
                'INNER JOIN `snr_h03.mka82.ct004.xq01` ON snr_h03.id = `snr_h03.mka82.ct004.xq01`.id ' ... #StatorColdAirTemp4
                'INNER JOIN `snr_h03.mka81.ct005.xq01` ON snr_h03.id = `snr_h03.mka81.ct005.xq01`.id ' ... #StatorHotAirTemp5
                'INNER JOIN `snr_h03.mka82.ct005.xq01` ON snr_h03.id = `snr_h03.mka82.ct005.xq01`.id ' ... #StatorColdAirTemp5
                'INNER JOIN `snr_h03.mka81.ct006.xq01` ON snr_h03.id = `snr_h03.mka81.ct006.xq01`.id ' ... #StatorHotAirTemp6
                'INNER JOIN `snr_h03.mka82.ct006.xq01` ON snr_h03.id = `snr_h03.mka82.ct006.xq01`.id ' ... #StatorColdAirTemp6
                'INNER JOIN `snr_h03.mka81.ct007.xq01` ON snr_h03.id = `snr_h03.mka81.ct007.xq01`.id ' ... #StatorHotAirTemp7
                'INNER JOIN `snr_h03.mka82.ct007.xq01` ON snr_h03.id = `snr_h03.mka82.ct007.xq01`.id ' ... #StatorColdAirTemp7
                'INNER JOIN `snr_h03.mka81.ct008.xq01` ON snr_h03.id = `snr_h03.mka81.ct008.xq01`.id ' ... #StatorHotAirTemp8
                'INNER JOIN `snr_h03.mka82.ct008.xq01` ON snr_h03.id = `snr_h03.mka82.ct008.xq01`.id ' ... #StatorColdAirTemp8
                'INNER JOIN `snr_h03.mka81.ct009.xq01` ON snr_h03.id = `snr_h03.mka81.ct009.xq01`.id ' ... #StatorHotAirTemp9
                'INNER JOIN `snr_h03.mka82.ct009.xq01` ON snr_h03.id = `snr_h03.mka82.ct009.xq01`.id ' ... #StatorColdAirTemp9
                'INNER JOIN `snr_h03.mka81.ct010.xq01` ON snr_h03.id = `snr_h03.mka81.ct010.xq01`.id ' ... #StatorHotAirTemp10
                'INNER JOIN `snr_h03.mka82.ct010.xq01` ON snr_h03.id = `snr_h03.mka82.ct010.xq01`.id ' ... #StatorColdAirTemp10
                'INNER JOIN `snr_h03.mka81.ct011.xq01` ON snr_h03.id = `snr_h03.mka81.ct011.xq01`.id ' ... #StatorHotAirTemp11
                'INNER JOIN `snr_h03.mka82.ct011.xq01` ON snr_h03.id = `snr_h03.mka82.ct011.xq01`.id ' ... #StatorColdAirTemp11
                'INNER JOIN `snr_h03.mka81.ct012.xq01` ON snr_h03.id = `snr_h03.mka81.ct012.xq01`.id ' ... #StatorHotAirTemp12
                'INNER JOIN `snr_h03.mka82.ct012.xq01` ON snr_h03.id = `snr_h03.mka82.ct012.xq01`.id ' ... #StatorColdAirTemp12
                'WHERE Date(snr_h03.rec_date) > date_sub(NOW(), interval 2 day) AND snr_h03.id > 10 LIMIT 2000']); 
            curs = fetch(curs);
            data = curs.Data;
            close(curs)
            close(conn)
            setdbprefs('DataReturnFormat',prefs)
            clear prefs conn curs   
        end
        
        function plotgraph(app)
            global data
            x = 0:1:height(data);
            y = data.MW;
            plot(app.UIAxes,y);  
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadb(app);
            plotgraph(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotgraph(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Monitoring')
            xlabel(app.UIAxes, 'datetime')
            ylabel(app.UIAxes, 'MW')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end